classdef OmniSTORM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OmniSTORMUIFigure               matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ParameterSetupPanel             matlab.ui.container.Panel
        EstimateEmitterIntensityThresholdCheckBox  matlab.ui.control.CheckBox
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        OffsetEditField                 matlab.ui.control.NumericEditField
        OffsetEditFieldLabel            matlab.ui.control.Label
        GainEditField                   matlab.ui.control.NumericEditField
        GainEditFieldLabel              matlab.ui.control.Label
        EstimateGainOffsetCheckBox      matlab.ui.control.CheckBox
        PixelSizenmEditField            matlab.ui.control.NumericEditField
        PixelSizenmEditFieldLabel       matlab.ui.control.Label
        ResolutionnmEditField           matlab.ui.control.NumericEditField
        ResolutionnmLabel               matlab.ui.control.Label
        ResolutionpxEditField           matlab.ui.control.NumericEditField
        ResolutionpxLabel               matlab.ui.control.Label
        EstimateInputResolutionCheckBox  matlab.ui.control.CheckBox
        LoadConfigButton                matlab.ui.control.Button
        SaveConfigButton                matlab.ui.control.Button
        PostProcessingPanel             matlab.ui.container.Panel
        AdjustContrastAutoCheckBox      matlab.ui.control.CheckBox
        EstimateResultResolutionCheckBox  matlab.ui.control.CheckBox
        ProcessingPanel                 matlab.ui.container.Panel
        ScaleEditField                  matlab.ui.control.NumericEditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        ProcessingMethodTabGroup        matlab.ui.container.TabGroup
        LocalizationTab                 matlab.ui.container.Tab
        AIMTrackIntervalEditField       matlab.ui.control.NumericEditField
        AIMTrackIntervalEditFieldLabel  matlab.ui.control.Label
        AIMDriftCorrectionCheckBox      matlab.ui.control.CheckBox
        LocalizationDropDown            matlab.ui.control.DropDown
        LocalizationDropDownLabel       matlab.ui.control.Label
        DeconvolutionTab                matlab.ui.container.Tab
        InterpolationDropDown           matlab.ui.control.DropDown
        InterpolationDropDownLabel      matlab.ui.control.Label
        TemporalAnalysisDropDown        matlab.ui.control.DropDown
        TemporalAnalysisDropDownLabel   matlab.ui.control.Label
        PreProcessingPanel              matlab.ui.container.Panel
        UsePhotonCountCheckBox          matlab.ui.control.CheckBox
        InterFrameDifferenceCheckBox    matlab.ui.control.CheckBox
        BackgroundRemovalCheckBox       matlab.ui.control.CheckBox
        RightPanel                      matlab.ui.container.Panel
        ProgressPanel                   matlab.ui.container.Panel
        ProgressLabel                   matlab.ui.control.Label
        ProgressAxes                    matlab.ui.control.UIAxes
        StopButton                      matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        BatchFolderButton               matlab.ui.control.Button
        LoadImageButton                 matlab.ui.control.Button
        ImageDisplayTabGroup            matlab.ui.container.TabGroup
        InputImageStackTab              matlab.ui.container.Tab
        InformationPanel                matlab.ui.container.Panel
        ContrastButtonInput             matlab.ui.control.Button
        FilenameLabel                   matlab.ui.control.Label
        InputImageParamPanel            matlab.ui.container.Panel
        FrameSlider                     matlab.ui.control.Slider
        FrameSliderLabel                matlab.ui.control.Label
        FrameSpinner                    matlab.ui.control.Spinner
        InputImageAxes                  matlab.ui.control.UIAxes
        SuperResolutionImageTab         matlab.ui.container.Tab
        SRImageParamPanel               matlab.ui.container.Panel
        SaveLocalizationsButton         matlab.ui.control.Button
        ContrastButtonSR                matlab.ui.control.Button
        SaveImageButton                 matlab.ui.control.Button
        SRImageAxes                     matlab.ui.control.UIAxes
        FRCTab                          matlab.ui.container.Tab
        FRCInfoPanel                    matlab.ui.container.Panel
        FRCResolutionnmEditField        matlab.ui.control.NumericEditField
        EstimatedResolutionpxEditField_2Label_2  matlab.ui.control.Label
        FRCResolutionpxEditField        matlab.ui.control.NumericEditField
        EstimatedResolutionpxEditField_2Label  matlab.ui.control.Label
        FRCSaveButton                   matlab.ui.control.Button
        FRCCurveAxes                    matlab.ui.control.UIAxes
        FRCImage2Axes                   matlab.ui.control.UIAxes
        FRCImage1Axes                   matlab.ui.control.UIAxes
        DriftTab                        matlab.ui.container.Tab
        DriftAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        % Input / output / intermediate results in single-image mode
        inputImageStack
        preprocessedImageStack
        processedImage
        frcImage1
        frcImage2
        displayedInputImageStack % The image to be displayed at "InputImageAxes"

        % Progress bar
        progressPatch % The patch figure (green-yellow rectangle) progress bar
        progressDone
        progressTotal
        
        future % The future object representing the background job
        
        % Used to store config temporarily.
        % Can be out-of-date. Use app.UI2Config() to get up-to-date config.
        config

        % For batch processing
        inputSubFolderList
        outputSubFolderList

        % Background process pool
        % The super-resolution pipeline runs in this pool
        % 
        % Benefits of using process pool:
        % 1. Super-resolution processing doesn't freeze the UI
        % 2. We can cancel processing at any time
        % 
        % Drawback:
        % 1. It is slow to create a process-based pool
        %    The pool has only 1 worker to minimize startup time
        %    But it is still slow (around 5 seconds)
        %
        % Why not thread pool? They are fast
        % - Thread pool cannot run mex functions.
        processPool

        % Struct to save localizations
        % Fields: X, Y, F, intensity
        localizations
    end

    
    methods (Access = private)
        %% Image Read
        function DrawInputImage(app)
            frame_id = floor(app.FrameSpinner.Value);
            imagesc(app.InputImageAxes, app.displayedInputImageStack(:,:,frame_id));
        end

        function UpdateDisplayedInputImage(app, displayedImage)
            app.displayedInputImageStack = displayedImage;

            app.UpdateFrameControl();
            
            % Draw image
            app.DrawInputImage();
        end

        % function EstimateResolutionDecorr(app)
        %     resolution = decorr_resolution(app.inputImageStack(:, :, 1));
        %     app.ResolutionpxEditField.Value = resolution;
        %     app.ResolutionnmEditField.Value = resolution * app.PixelSizenmEditField.Value;
        % end
        % 
        % function EstimateGainOffset(app)
        %     [gain_pcfo,offset_pcfo] = pcfo(app.inputImageStack(:, :, 1),0.9,0,[3 3],0);
        %     app.GainEditField.Value = gain_pcfo;
        %     app.OffsetEditField.Value = offset_pcfo;
        % end

        function DisableFrameControl(app)
            app.FrameSlider.Enable = "off";
            app.FrameSpinner.Enable = "off";
        end

        function EnableFrameControl(app)
            app.FrameSlider.Enable = "on";
            app.FrameSpinner.Enable = "on";
        end

        % Update frame slider
        function UpdateFrameControl(app)
            n_frames = size(app.displayedInputImageStack, 3);

            if n_frames == 1
                app.DisableFrameControl()
                app.FrameSlider.Value = 1;
                app.FrameSlider.Limits = [1 2];
                app.FrameSpinner.Value = 1;
                app.FrameSpinner.Limits = [1 n_frames];
            elseif n_frames > 1
                app.FrameSlider.Limits = [1 n_frames];
                app.FrameSlider.Value = 1;
    
                app.FrameSpinner.Limits = [1 n_frames];
                app.FrameSpinner.Value = 1;
                app.EnableFrameControl()
            end
        end

        function UpdateInterFrameDifferenceCheckBox(app)
            nFrames = size(app.inputImageStack, 3);
            if nFrames > 100
                app.InterFrameDifferenceCheckBox.Value = false;
                app.InterFrameDifferenceCheckBox.Enable = "off";
            else
                app.InterFrameDifferenceCheckBox.Enable = "on";
            end
        end

        function ErrorPopUp(app, msg, title)
            errordlg(msg, title)
        end

        function CheckErrorsInFutureArray(app, futures)
            errors = [futures.Error];

            for i = 1:numel(errors)
                error = errors(i);
                app.ErrorPopUp(error.message, error.identifier);
                disp(getReport(error));
            end
        end

        function SaveLocalizations(app, filename)
            locTable = table;
            locTable.X = app.localizations.X;
            locTable.Y = app.localizations.Y;
            locTable.frameId = app.localizations.F;
            locTable.intensity = app.localizations.intensity;
            writetable(locTable, filename);
        end

        function InitProcessPool(app)
            if isempty(app.processPool) || isempty(gcp('nocreate'))
                delete(gcp('nocreate'));
                app.processPool = parpool("Processes", 1);
            end
        end

        %% SR Pipeline

        % When user clicks "Run" button, an SR pipeline is launched.
        % It has 3 stages: preprocess, process, and postprocess.
        % The real work runs in the background.
        % After each stage is done, a On[StageName]Done callback is called,
        % updating the UI to display intermediate result and progress bar.

        % Launch super-resolution pipeline in background.
        function LaunchSRPipeline(app)
            app.RunButton.Text = "Preprocessing";
            app.UpdateProgressBar(0, 3);

            app.config = app.UI2Config();
            try
                ConfigSanityCheck(app.inputImageStack, app.config);
            catch e
                app.ErrorPopUp(e.message, e.identifier);
                return
            end

            % Init process pool
            app.InitProcessPool();

            f(1) = parfeval(app.processPool, @Preprocess, 2, app.inputImageStack, app.config);
            app.future = f;

            % FRC resolution estimation is logically a post-process step,
            % But it needs to split the image stack at the very first.
            if app.config.postprocess.frc
                nFrames = size(app.inputImageStack, 3);
                oddFrames = app.inputImageStack(:, :, 1:2:nFrames);
                evenFrames = app.inputImageStack(:, :, 2:2:nFrames);
                f(2) = parfeval(app.processPool, @Preprocess, 2, oddFrames, app.config);
                f(3) = parfeval(app.processPool, @Preprocess, 2, evenFrames, app.config);
            end

            afterAll(f, @(futures) app.OnPreprocessDone(futures), 0, PassFuture=true);
        end

        % Update preprocessing result in display and launch processing in background
        function OnPreprocessDone(app, preprocessFutures)
            disp("on preprocess done");

            app.CheckErrorsInFutureArray(preprocessFutures);
            
            [preprocessedImage, mainBackground] = fetchOutputs(preprocessFutures(1));
            app.preprocessedImageStack = preprocessedImage;
            app.UpdateDisplayedInputImage(app.preprocessedImageStack);
            app.RunButton.Text = "Processing";
            app.UpdateProgressBar(1, 3);

            f(1) = parfeval(app.processPool, @Process, 6, preprocessedImage, mainBackground, app.config);

            % FRC resolution estimation
            if app.config.postprocess.frc
                [oddFrames, oddBackground] = fetchOutputs(preprocessFutures(2));
                [evenFrames, evenBackground] = fetchOutputs(preprocessFutures(3));
                f(2) = parfeval(app.processPool, @Process, 6, oddFrames, oddBackground, app.config);
                f(3) = parfeval(app.processPool, @Process, 6, evenFrames, evenBackground, app.config);
            end

            app.future = f;

            afterAll(f, @(processResult) app.OnProcessDone(processResult), 0, PassFuture=true);
            
            disp("on preprocess done end");
        end

        function OnProcessDone(app, processFutures)
            disp("on process done");

            app.CheckErrorsInFutureArray(processFutures);

            app.RunButton.Text = "Postprocessing";
            app.UpdateProgressBar(2, 3);

            [app.processedImage, X, Y, F, intensity, drift] = fetchOutputs(processFutures(1));
            
            % Draw processed image
            imagesc(app.SRImageAxes, app.processedImage);
            f(1) = parfeval(app.processPool, @Postprocess, 1, app.processedImage, app.config);

            % Save localizations to app instance
            app.localizations.X = X;
            app.localizations.Y = Y;
            app.localizations.F = F;
            app.localizations.intensity = intensity;

            if app.config.postprocess.frc
                [processedOddFrames, ~, ~, ~, ~, ~]  = fetchOutputs(processFutures(2));
                [processedEvenFrames, ~, ~, ~, ~, ~] = fetchOutputs(processFutures(3));
                imagesc(app.FRCImage1Axes, processedOddFrames);
                imagesc(app.FRCImage2Axes, processedEvenFrames);
                f(2) = parfeval(app.processPool, @Postprocess, 1, processedOddFrames, app.config);
                f(3) = parfeval(app.processPool, @Postprocess, 1, processedEvenFrames, app.config);
                app.frcImage1 = processedOddFrames;
                app.frcImage2 = processedEvenFrames;
            end

            cla(app.DriftAxes);
            if app.config.process.sparse.aim && app.config.process.method == "Localization"
                disp('if aim start')
                hold(app.DriftAxes, 'on');
                plot(app.DriftAxes, drift(:,1), 'DisplayName', 'Drift X');
                plot(app.DriftAxes, drift(:,2), 'DisplayName', 'Drift Y');
                legend(app.DriftAxes, 'show');
                hold(app.DriftAxes, 'off');
                disp('if aim end');
            end

            app.future = f;
            afterAll(f, @(postprocessFutures) app.OnPostprocessDone(postprocessFutures), 0, PassFuture=true);
            app.ImageDisplayTabGroup.SelectedTab = app.SuperResolutionImageTab;
            disp("on process done end");
        end
        

        function OnPostprocessDone(app, postprocessFutures)
            disp("on postprocess done");

            app.CheckErrorsInFutureArray(postprocessFutures);

            app.processedImage = fetchOutputs(postprocessFutures(1));
            app.RunButton.Text = "Run";
            app.EnableRunButton();
            app.HideProgressBar();
            
            % Draw postprocessed image
            imagesc(app.SRImageAxes, app.processedImage);

            cla(app.FRCCurveAxes);
            if app.config.postprocess.frc
                disp('in frc')
                postprocessedOddFrames  = fetchOutputs(postprocessFutures(2));
                postprocessedEvenFrames = fetchOutputs(postprocessFutures(3));
                imagesc(app.FRCImage1Axes, postprocessedOddFrames);
                imagesc(app.FRCImage2Axes, postprocessedEvenFrames);
                app.frcImage1 = postprocessedOddFrames;
                app.frcImage2 = postprocessedEvenFrames;

                % Plot FRC curve
                disp('enter curve')
                frcParams.beta = 7;
                frc = FSC(postprocessedOddFrames, postprocessedEvenFrames, frcParams);
                smoothedFRC = smooth(smooth(frc.frc));
                cla(app.FRCCurveAxes);
                hold(app.FRCCurveAxes, 'on');
                plot(app.FRCCurveAxes, frc.nu, smoothedFRC, 'DisplayName', 'FRC');
                plot(app.FRCCurveAxes, frc.nu, frc.T_hbit, 'DisplayName', '1/2 bit Threshold');
                legend(app.FRCCurveAxes, 'show');
                hold(app.FRCCurveAxes, 'off');

                % Calculate Resolution
                disp('calc res')
                resolution = 0;
                for i = 1 : size(smoothedFRC, 1)
                    if smoothedFRC(i) < frc.T_hbit(i)
                        resolution = 1 / frc.nu(i);
                        break
                    end
                end

                app.FRCResolutionpxEditField.Value = resolution;
                app.FRCResolutionnmEditField.Value = resolution * app.config.params.pixelSize / app.config.process.scale;
            end

            disp("on postprocess done end");
        end

        function EnableRunButton(app)
            app.RunButton.Text = "Run";

            if ~isempty(app.inputImageStack)
                app.RunButton.Enable = "on";
            end

            app.BatchFolderButton.Enable = "on";

            app.StopButton.Enable = "off";
        end

        function DisableRunButton(app)
            app.RunButton.Enable = "off";

            app.BatchFolderButton.Enable = "off";

            app.StopButton.Enable = "on";
        end

        %% Batch Folder
        function LaunchBatchFolder(app, folderPath)
            disp('launch batch folder begin');

            app.DisableRunButton();
            outputFolder = [folderPath '-OmniSTORM'];
            mkdir(outputFolder);

            app.config = app.UI2Config();

            % Init process pool
            app.InitProcessPool();

            files = dir(folderPath);
            n_subfolders = 0;
            app.inputSubFolderList = string([]);
            app.outputSubFolderList = string([]);
            for i = 1 : length(files)
                if ~files(i).isdir
                    continue;
                end
                if strcmp(files(i).name, '.') || strcmp(files(i).name, '..')
                    continue;
                end
                
                subFolder = fullfile(folderPath, files(i).name);
                outputSubFolder = fullfile(outputFolder, files(i).name);
                n_subfolders = n_subfolders + 1;
                app.inputSubFolderList(n_subfolders) = string(subFolder);
                app.outputSubFolderList(n_subfolders) = string(outputSubFolder);
            end

            app.progressTotal = n_subfolders;
            app.progressDone = 0;

            if n_subfolders == 0
                app.EnableRunButton();
                return
            end
            
            f = parfeval(app.processPool, @RunMultiPart, 0, app.inputSubFolderList(1), app.outputSubFolderList(1), app.config);
            afterAll(f, @() app.OnSubFolderDone(), 0);
            app.future = f;
            
            DisplayBackgroundError(f);
            disp('launch batch folder end');
        end

        function OnSubFolderDone(app)
            disp('on subfolder done ' + string(app.progressDone));
            app.CheckErrorsInFutureArray(app.future);

            app.progressDone = app.progressDone + 1;
            app.UpdateProgressBar(app.progressDone, app.progressTotal);

            if app.progressDone == app.progressTotal
                app.EnableRunButton();
                app.HideProgressBar();
                return
            end

            % Process next subfolder
            f = parfeval(app.processPool, @RunMultiPart, 0, app.inputSubFolderList(app.progressDone + 1), app.outputSubFolderList(app.progressDone + 1), app.config);
            afterAll(f, @() app.OnSubFolderDone(), 0);
            app.future = f;
            disp('on subfolder done end');
        end

        %% Config Load/Save
        function Config2UI(app, config)
            app.EstimateInputResolutionCheckBox.Value = config.params.decorr;
            app.PixelSizenmEditField.Value = config.params.pixelSize;
            app.ResolutionpxEditField.Value = config.params.resolutionPx;
            app.ResolutionnmEditField.Value = app.ResolutionpxEditField.Value * app.PixelSizenmEditField.Value;

            app.EstimateGainOffsetCheckBox.Value = config.params.estimateGainOffset;
            app.GainEditField.Value = config.params.gain;
            app.OffsetEditField.Value = config.params.offset;

            app.ThresholdEditField.Value = config.params.threshold;
            app.EstimateEmitterIntensityThresholdCheckBox.Value = config.params.estimateThreshold;
            
            app.UsePhotonCountCheckBox.Value = config.preprocess.usePhotonCount;
            app.BackgroundRemovalCheckBox.Value = config.preprocess.backgroundRemoval;
            app.InterFrameDifferenceCheckBox.Value = config.preprocess.interFrameDifference;
            
            app.ScaleEditField.Value = config.process.scale;

            tabs = app.ProcessingMethodTabGroup.Children;
            for i = 1:size(tabs, 1)
                tab = tabs(i);
                if isequal(tab.Title, config.process.method)
                    app.ProcessingMethodTabGroup.SelectedTab = tab;
                    break;
                end
            end
            
            app.TemporalAnalysisDropDown.Value = config.process.extraDense.temporal;
            app.InterpolationDropDown.Value = config.process.extraDense.interpolation;
            
            app.LocalizationDropDown.Value = config.process.sparse.localization;
            app.AIMDriftCorrectionCheckBox.Value = config.process.sparse.aim;
            app.AIMTrackIntervalEditField.Value = config.process.sparse.aimTrackInterval;
            
            app.EstimateResultResolutionCheckBox.Value = config.postprocess.frc;
            app.AdjustContrastAutoCheckBox.Value = config.postprocess.adjustContrast;   

        end

        function config = UI2Config(app)
            config.params.decorr = app.EstimateInputResolutionCheckBox.Value;
            config.params.pixelSize = app.PixelSizenmEditField.Value;
            config.params.resolutionPx = app.ResolutionpxEditField.Value;

            config.params.estimateGainOffset = app.EstimateGainOffsetCheckBox.Value;
            config.params.gain = app.GainEditField.Value;
            config.params.offset = app.OffsetEditField.Value;

            config.params.estimateThreshold = app.EstimateEmitterIntensityThresholdCheckBox.Value;
            config.params.threshold = app.ThresholdEditField.Value;

            config.preprocess.usePhotonCount = app.UsePhotonCountCheckBox.Value;
            config.preprocess.backgroundRemoval = app.BackgroundRemovalCheckBox.Value;
            config.preprocess.interFrameDifference = app.InterFrameDifferenceCheckBox.Value;
            
            config.process.scale = app.ScaleEditField.Value;
            config.process.method = app.ProcessingMethodTabGroup.SelectedTab.Title;

            config.process.extraDense.temporal = app.TemporalAnalysisDropDown.Value;
            config.process.extraDense.interpolation = app.InterpolationDropDown.Value;
            
            config.process.sparse.localization = app.LocalizationDropDown.Value;
            config.process.sparse.aim = app.AIMDriftCorrectionCheckBox.Value;
            config.process.sparse.aimTrackInterval = app.AIMTrackIntervalEditField.Value;

            config.postprocess.frc = app.EstimateResultResolutionCheckBox.Value;
            config.postprocess.adjustContrast = app.AdjustContrastAutoCheckBox.Value;
        end

        %% Progresss bar
        function UpdateProgressBar(app, num_done, num_total)
            app.ProgressPanel.Visible = "on";

            app.ProgressLabel.Text = string(num_done) + "/" + string(num_total);

            patchLength = num_done / num_total;
            app.progressPatch.XData = [0 patchLength patchLength 0];
        end
        
        function HideProgressBar(app)
            app.ProgressPanel.Visible = "off";
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Init progress bar
            app.progressPatch = patch(app.ProgressAxes, [0 0 0 0], [0 0 1 1], [0.67578 1 0.18359]); %greenyellow
            disableDefaultInteractivity(app.ProgressAxes);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Read image stack
            filterspec = {'*.tif;*.dcimg'};
            [file, path] = uigetfile(filterspec);
            if isequal(file, 0) && isequal(path, 0)
                return
            end
            imagefile = [path file];
            app.inputImageStack = read_img_auto(imagefile);
            app.inputImageStack = double(app.inputImageStack);

            % Crop input image to square
            [height, width] = size(app.inputImageStack);
            if height ~= width
                crop_size = min([height, width]);
                app.inputImageStack = app.inputImageStack(1:crop_size, 1:crop_size, :);
            end

            % Update filename
            sizeStr = sprintf(' (%d x %d x %d)', ...
                size(app.inputImageStack, 1), size(app.inputImageStack, 2), size(app.inputImageStack, 3));
            app.FilenameLabel.Text = strcat(file, sizeStr);

            % Estimate Params (resolution, gain/offset, threshold)
            conf = app.UI2Config();
            conf.params = EstimateParams(app.inputImageStack, conf);
            app.Config2UI(conf);

            app.UpdateDisplayedInputImage(app.inputImageStack);
            app.ImageDisplayTabGroup.SelectedTab = app.InputImageStackTab;

            app.UpdateInterFrameDifferenceCheckBox();

            app.EnableRunButton();

        end

        % Value changed function: FrameSpinner
        function FrameSpinnerValueChanged(app, event)
            value = app.FrameSpinner.Value;
            app.FrameSlider.Value = round(value);
            app.DrawInputImage();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.DisableRunButton();
            app.LaunchSRPipeline();
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            [file, path] = uiputfile('*.tif', 'Save as TIFF', 'super-resolution-image.tif');
            if isequal(file, 0) && isequal(path, 0)
                return
            end
            save_tiff(app.processedImage, [path file]);
        end

        % Value changed function: ResolutionpxEditField
        function ResolutionpxEditFieldValueChanged(app, event)
            value = app.ResolutionpxEditField.Value;
            app.ResolutionnmEditField.Value = value * app.PixelSizenmEditField.Value;
        end

        % Value changed function: PixelSizenmEditField
        function PixelSizenmEditFieldValueChanged(app, event)
            value = app.PixelSizenmEditField.Value;
            app.ResolutionnmEditField.Value = value * app.ResolutionpxEditField.Value;
        end

        % Value changed function: ResolutionnmEditField
        function ResolutionnmEditFieldValueChanged(app, event)
            value = app.ResolutionnmEditField.Value;
            app.ResolutionpxEditField.Value =  value / app.PixelSizenmEditField.Value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            disp("Stop requested");
            if ~isempty(app.future)
                cancel(app.future)
            end
            app.EnableRunButton();
            app.HideProgressBar();
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
            [file, path] = uiputfile('*.json', 'Save config', 'config.json');
            if isequal(file, 0) && isequal(path, 0)
                return
            end
            conf = app.UI2Config();
            writestruct(conf, [path file], PrettyPrint=true);
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            filterspec = {'*.json;'};
            [file, path] = uigetfile(filterspec);
            if isequal(file, 0) && isequal(path, 0)
                return
            end
            configFilename = [path file];
            conf = readstruct(configFilename);
            app.Config2UI(conf);
        end

        % Button pushed function: ContrastButtonSR
        function ContrastButtonSRPushed(app, event)
            imcontrast(app.SRImageAxes);
        end

        % Value changed function: EstimateInputResolutionCheckBox
        function EstimateInputResolutionCheckBoxValueChanged(app, event)
            value = app.EstimateInputResolutionCheckBox.Value;
            if value == true
                app.ResolutionnmEditField.Editable = "off";
                app.ResolutionpxEditField.Editable = "off";
            elseif value == false
                app.ResolutionnmEditField.Editable = "on";
                app.ResolutionpxEditField.Editable = "on";
            end
        end

        % Button pushed function: ContrastButtonInput
        function ContrastButtonInputPushed(app, event)
            imcontrast(app.InputImageAxes);
        end

        % Button pushed function: FRCSaveButton
        function FRCSaveButtonPushed(app, event)
            selpath = uigetdir();
            if isequal(selpath, 0)
                return
            end
            save_tiff(app.frcImage1, fullfile(selpath, 'frc-image-1.tif'));
            save_tiff(app.frcImage2, fullfile(selpath, 'frc-image-2.tif'));
            exportgraphics(app.FRCCurveAxes, fullfile(selpath, 'frc-curve.png'));
        end

        % Button pushed function: BatchFolderButton
        function BatchFolderButtonPushed(app, event)
            selpath = uigetdir();
            if isequal(selpath, 0)
                return
            end
            app.LaunchBatchFolder(selpath);
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            value = event.Value;
            app.FrameSpinner.Value = round(value);
            app.DrawInputImage();
        end

        % Button pushed function: SaveLocalizationsButton
        function SaveLocalizationsButtonPushed(app, event)
            [file, path] = uiputfile('*.csv', 'Save as CSV', 'localizations.csv');
            if isequal(file, 0) && isequal(path, 0)
                return
            end
            app.SaveLocalizations([path file]);
        end

        % Value changed function: EstimateGainOffsetCheckBox
        function EstimateGainOffsetCheckBoxValueChanged(app, event)
            value = app.EstimateGainOffsetCheckBox.Value;
            if value == true
                app.GainEditField.Editable = "off";
                app.OffsetEditField.Editable = "off";
            elseif value == false
                app.GainEditField.Editable = "on";
                app.OffsetEditField.Editable = "on";
            end
        end

        % Value changed function: EstimateEmitterIntensityThresholdCheckBox
        function EstimateEmitterIntensityThresholdCheckBoxValueChanged(app, event)
            value = app.EstimateEmitterIntensityThresholdCheckBox.Value;
            if value == true
                app.ThresholdEditField.Editable = "off";
            elseif value == false
                app.ThresholdEditField.Editable = "on";
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.OmniSTORMUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {745, 745};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {268, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OmniSTORMUIFigure = uifigure('Visible', 'off');
            app.OmniSTORMUIFigure.Name = 'OmniSTORM';
            app.OmniSTORMUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.OmniSTORMUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PreProcessingPanel = uipanel(app.LeftPanel);
            app.PreProcessingPanel.Title = 'Pre-Processing';
            app.BackgroundRemovalCheckBox = uicheckbox(app.PreProcessingPanel);
            app.BackgroundRemovalCheckBox.Text = 'Background Removal';
            app.InterFrameDifferenceCheckBox = uicheckbox(app.PreProcessingPanel);
            app.InterFrameDifferenceCheckBox.Tooltip = {'Compute inter-frame differnce to increase number of localizations when frame number is small. If frame number > 100 it will be very slow, and the checkbox will be disabled.'};
            app.InterFrameDifferenceCheckBox.Text = 'Inter-Frame Difference';
            app.UsePhotonCountCheckBox = uicheckbox(app.PreProcessingPanel);
            app.UsePhotonCountCheckBox.Tooltip = {'Use photon count instead of pixel intensity in super-resolution processing. Photon count = (intensity - offset) / gain'};
            app.UsePhotonCountCheckBox.Text = 'Use Photon Count';
            app.ProcessingPanel = uipanel(app.LeftPanel);
            app.ProcessingPanel.Title = 'Processing';
            app.ProcessingMethodTabGroup = uitabgroup(app.ProcessingPanel);
            app.LocalizationTab = uitab(app.ProcessingMethodTabGroup);
            app.LocalizationTab.Title = 'Localization';
            app.LocalizationDropDownLabel = uilabel(app.LocalizationTab);
            app.LocalizationDropDownLabel.Text = 'Localization';
            app.LocalizationDropDown = uidropdown(app.LocalizationTab);
            app.LocalizationDropDown.Items = {'Sonic', 'WindSTORM'};
            app.LocalizationDropDown.Value = 'Sonic';
            app.AIMDriftCorrectionCheckBox = uicheckbox(app.LocalizationTab);
            app.AIMDriftCorrectionCheckBox.Text = 'AIM Drift Correction';
            app.AIMTrackIntervalEditFieldLabel = uilabel(app.LocalizationTab);
            app.AIMTrackIntervalEditFieldLabel.Text = 'AIM Track Interval';
            app.AIMTrackIntervalEditField = uieditfield(app.LocalizationTab, 'numeric');
            app.AIMTrackIntervalEditField.RoundFractionalValues = 'on';
            app.AIMTrackIntervalEditField.Value = 20;
            app.DeconvolutionTab = uitab(app.ProcessingMethodTabGroup);
            app.DeconvolutionTab.Title = 'Deconvolution';
            app.TemporalAnalysisDropDownLabel = uilabel(app.DeconvolutionTab);
            app.TemporalAnalysisDropDownLabel.Text = 'Temporal Analysis';
            app.TemporalAnalysisDropDown = uidropdown(app.DeconvolutionTab);
            app.TemporalAnalysisDropDown.Items = {'mean', 'std'};
            app.TemporalAnalysisDropDown.Value = 'std';
            app.InterpolationDropDownLabel = uilabel(app.DeconvolutionTab);
            app.InterpolationDropDownLabel.Text = 'Interpolation';
            app.InterpolationDropDown = uidropdown(app.DeconvolutionTab);
            app.InterpolationDropDown.Items = {'bicubic', 'fourier'};
            app.InterpolationDropDown.Value = 'bicubic';
            app.ScaleEditFieldLabel = uilabel(app.ProcessingPanel);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.ProcessingPanel, 'numeric');
            app.ScaleEditField.Limits = [1 Inf];
            app.ScaleEditField.Value = 8;
            app.PostProcessingPanel = uipanel(app.LeftPanel);
            app.PostProcessingPanel.Title = 'Post-Processing';
            app.EstimateResultResolutionCheckBox = uicheckbox(app.PostProcessingPanel);
            app.EstimateResultResolutionCheckBox.Tooltip = {'Split the original image stack in half, and generate 2 super-resolution images. Use FRC to estimate the resolution of super-resolution image.'};
            app.EstimateResultResolutionCheckBox.Text = 'Estimate Result Resolution';
            app.EstimateResultResolutionCheckBox.Value = true;
            app.AdjustContrastAutoCheckBox = uicheckbox(app.PostProcessingPanel);
            app.AdjustContrastAutoCheckBox.Text = 'Adjust Contrast (Auto)';
            app.AdjustContrastAutoCheckBox.Value = true;
            app.SaveConfigButton = uibutton(app.LeftPanel, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save Config';
            app.LoadConfigButton = uibutton(app.LeftPanel, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.ParameterSetupPanel = uipanel(app.LeftPanel);
            app.ParameterSetupPanel.Title = 'Parameter Setup';
            app.EstimateInputResolutionCheckBox = uicheckbox(app.ParameterSetupPanel);
            app.EstimateInputResolutionCheckBox.ValueChangedFcn = createCallbackFcn(app, @EstimateInputResolutionCheckBoxValueChanged, true);
            app.EstimateInputResolutionCheckBox.Tooltip = {'Estimate input resolution using decorrelation method. The estimated resolution will be used to estimate PSF during super-resolution.'};
            app.EstimateInputResolutionCheckBox.Text = 'Estimate Input Resolution';
            app.EstimateInputResolutionCheckBox.Value = true;
            app.ResolutionpxLabel = uilabel(app.ParameterSetupPanel);
            app.ResolutionpxLabel.Text = 'Resolution (px)';
            app.ResolutionpxEditField = uieditfield(app.ParameterSetupPanel, 'numeric');
            app.ResolutionpxEditField.ValueDisplayFormat = '%.2f';
            app.ResolutionpxEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionpxEditFieldValueChanged, true);
            app.ResolutionpxEditField.Editable = 'off';
            app.ResolutionnmLabel = uilabel(app.ParameterSetupPanel);
            app.ResolutionnmLabel.Text = 'Resolution (nm)';
            app.ResolutionnmEditField = uieditfield(app.ParameterSetupPanel, 'numeric');
            app.ResolutionnmEditField.ValueDisplayFormat = '%.2f';
            app.ResolutionnmEditField.ValueChangedFcn = createCallbackFcn(app, @ResolutionnmEditFieldValueChanged, true);
            app.ResolutionnmEditField.Editable = 'off';
            app.PixelSizenmEditFieldLabel = uilabel(app.ParameterSetupPanel);
            app.PixelSizenmEditFieldLabel.Text = 'Pixel Size (nm)';
            app.PixelSizenmEditField = uieditfield(app.ParameterSetupPanel, 'numeric');
            app.PixelSizenmEditField.ValueDisplayFormat = '%.2f';
            app.PixelSizenmEditField.ValueChangedFcn = createCallbackFcn(app, @PixelSizenmEditFieldValueChanged, true);
            app.PixelSizenmEditField.Value = 100;
            app.EstimateGainOffsetCheckBox = uicheckbox(app.ParameterSetupPanel);
            app.EstimateGainOffsetCheckBox.ValueChangedFcn = createCallbackFcn(app, @EstimateGainOffsetCheckBoxValueChanged, true);
            app.EstimateGainOffsetCheckBox.Text = 'Estimate Gain/Offset';
            app.EstimateGainOffsetCheckBox.Value = true;
            app.GainEditFieldLabel = uilabel(app.ParameterSetupPanel);
            app.GainEditFieldLabel.Tooltip = {'Digital count (intensity) / photon count'};
            app.GainEditFieldLabel.Text = 'Gain ';
            app.GainEditField = uieditfield(app.ParameterSetupPanel, 'numeric');
            app.GainEditField.ValueDisplayFormat = '%.2f';
            app.GainEditField.Editable = 'off';
            app.GainEditField.Tooltip = {''};
            app.GainEditField.Value = 1;
            app.OffsetEditFieldLabel = uilabel(app.ParameterSetupPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.ParameterSetupPanel, 'numeric');
            app.OffsetEditField.ValueDisplayFormat = '%.2f';
            app.OffsetEditField.Editable = 'off';
            app.ThresholdEditFieldLabel = uilabel(app.ParameterSetupPanel);
            app.ThresholdEditFieldLabel.Tooltip = {'Pixel intensity less than threshold will be suppressed  to 0.'};
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.ParameterSetupPanel, 'numeric');
            app.ThresholdEditField.Editable = 'off';
            app.ThresholdEditField.Tooltip = {''};
            app.ThresholdEditField.Value = 100;
            app.EstimateEmitterIntensityThresholdCheckBox = uicheckbox(app.ParameterSetupPanel);
            app.EstimateEmitterIntensityThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @EstimateEmitterIntensityThresholdCheckBoxValueChanged, true);
            app.EstimateEmitterIntensityThresholdCheckBox.Text = 'Estimate Emitter Intensity Threshold';
            app.EstimateEmitterIntensityThresholdCheckBox.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.ImageDisplayTabGroup = uitabgroup(app.RightPanel);
            app.InputImageStackTab = uitab(app.ImageDisplayTabGroup);
            app.InputImageStackTab.Title = 'Input Image Stack';
            app.InputImageAxes = uiaxes(app.InputImageStackTab);
            app.InputImageAxes.XTick = [];
            app.InputImageAxes.XTickLabel = '';
            app.InputImageAxes.YTick = [];
            colormap(app.InputImageAxes, 'gray')
            app.InputImageParamPanel = uipanel(app.InputImageStackTab);
            app.FrameSpinner = uispinner(app.InputImageParamPanel);
            app.FrameSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameSpinnerValueChanged, true);
            app.FrameSliderLabel = uilabel(app.InputImageParamPanel);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.InputImageParamPanel);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.InformationPanel = uipanel(app.InputImageStackTab);
            app.InformationPanel.Title = 'Information';
            app.FilenameLabel = uilabel(app.InformationPanel);
            app.FilenameLabel.Text = 'Filename';
            app.ContrastButtonInput = uibutton(app.InformationPanel, 'push');
            app.ContrastButtonInput.ButtonPushedFcn = createCallbackFcn(app, @ContrastButtonInputPushed, true);
            app.ContrastButtonInput.Text = 'Adjust Contrast';
            app.SuperResolutionImageTab = uitab(app.ImageDisplayTabGroup);
            app.SuperResolutionImageTab.Title = 'Super-Resolution Image';
            app.SRImageAxes = uiaxes(app.SuperResolutionImageTab);
            app.SRImageAxes.XTick = [];
            app.SRImageAxes.XTickLabel = '';
            app.SRImageAxes.YTick = [];
            colormap(app.SRImageAxes, 'gray')
            app.SRImageParamPanel = uipanel(app.SuperResolutionImageTab);
            app.SRImageParamPanel.Title = 'Operations';
            app.SaveImageButton = uibutton(app.SRImageParamPanel, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.ContrastButtonSR = uibutton(app.SRImageParamPanel, 'push');
            app.ContrastButtonSR.ButtonPushedFcn = createCallbackFcn(app, @ContrastButtonSRPushed, true);
            app.ContrastButtonSR.Text = 'Adjust Contrast';
            app.SaveLocalizationsButton = uibutton(app.SRImageParamPanel, 'push');
            app.SaveLocalizationsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLocalizationsButtonPushed, true);
            app.SaveLocalizationsButton.Text = 'Save Localizations';
            app.FRCTab = uitab(app.ImageDisplayTabGroup);
            app.FRCTab.Title = 'FRC';
            app.FRCImage1Axes = uiaxes(app.FRCTab);
            title(app.FRCImage1Axes, 'FRC Image 1 (Odd Frames)')
            app.FRCImage1Axes.XTick = [];
            app.FRCImage1Axes.XTickLabel = '';
            app.FRCImage1Axes.YTick = [];
            colormap(app.FRCImage1Axes, 'gray')
            app.FRCImage2Axes = uiaxes(app.FRCTab);
            title(app.FRCImage2Axes, 'FRC Image 2 (Even Frames)')
            app.FRCImage2Axes.XTick = [];
            app.FRCImage2Axes.XTickLabel = '';
            app.FRCImage2Axes.YTick = [];
            colormap(app.FRCImage2Axes, 'gray')
            app.FRCCurveAxes = uiaxes(app.FRCTab);
            title(app.FRCCurveAxes, 'FRC Curve')
            xlabel(app.FRCCurveAxes, 'Frequency')
            app.FRCInfoPanel = uipanel(app.FRCTab);
            app.FRCInfoPanel.Title = 'Information';
            app.FRCSaveButton = uibutton(app.FRCInfoPanel, 'push');
            app.FRCSaveButton.ButtonPushedFcn = createCallbackFcn(app, @FRCSaveButtonPushed, true);
            app.FRCSaveButton.Text = 'Save Curve and Images';
            app.EstimatedResolutionpxEditField_2Label = uilabel(app.FRCInfoPanel);
            app.EstimatedResolutionpxEditField_2Label.Text = 'Estimated Resolution (px)';
            app.FRCResolutionpxEditField = uieditfield(app.FRCInfoPanel, 'numeric');
            app.FRCResolutionpxEditField.Editable = 'off';
            app.EstimatedResolutionpxEditField_2Label_2 = uilabel(app.FRCInfoPanel);
            app.EstimatedResolutionpxEditField_2Label_2.Text = 'Estimated Resolution (nm)';
            app.FRCResolutionnmEditField = uieditfield(app.FRCInfoPanel, 'numeric');
            app.FRCResolutionnmEditField.Editable = 'off';
            app.DriftTab = uitab(app.ImageDisplayTabGroup);
            app.DriftTab.Title = 'Drift';
            app.DriftAxes = uiaxes(app.DriftTab);
            title(app.DriftAxes, 'Estimated Drift')
            xlabel(app.DriftAxes, 'Frame')
            ylabel(app.DriftAxes, 'Drift (px)')
            zlabel(app.DriftAxes, 'Z')
            app.LoadImageButton = uibutton(app.RightPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.BatchFolderButton = uibutton(app.RightPanel, 'push');
            app.BatchFolderButton.ButtonPushedFcn = createCallbackFcn(app, @BatchFolderButtonPushed, true);
            app.BatchFolderButton.Tooltip = {'Batch process a folder of subfolders. All images in a subfolder are used to construct a single super-resolution image.'};
            app.BatchFolderButton.Text = 'Batch Folder';
            app.RunButton = uibutton(app.RightPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.RightPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.ProgressPanel = uipanel(app.RightPanel);
            app.ProgressPanel.Visible = 'off';
            app.ProgressAxes = uiaxes(app.ProgressPanel);
            app.ProgressAxes.XLim = [0 1];
            app.ProgressAxes.YLim = [0 1];
            app.ProgressAxes.ZLim = [0 1];
            app.ProgressAxes.XTick = [];
            app.ProgressAxes.YTick = [];
            app.ProgressAxes.Visible = 'off';
            app.ProgressLabel = uilabel(app.ProgressPanel);
            app.ProgressLabel.Text = '0/0';
            % Show the figure after all components are created
            app.OmniSTORMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OmniSTORM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OmniSTORMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OmniSTORMUIFigure)
        end
    end
end